classdef ProjectPPT_21200326 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        NhpdliuPanel                  matlab.ui.container.Panel
        Button                        matlab.ui.control.StateButton
        ChnphngphphiquyDropDownLabel  matlab.ui.control.Label
        DropDownOption                matlab.ui.control.DropDown
        xEditFieldLabel               matlab.ui.control.Label
        xEditField                    matlab.ui.control.EditField
        yEditFieldLabel               matlab.ui.control.Label
        yEditField                    matlab.ui.control.EditField
        KtquTextAreaLabel             matlab.ui.control.Label
        KtquTextArea                  matlab.ui.control.TextArea
        VthPanel                      matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        Button2                       matlab.ui.control.StateButton
        ChnbcathcLabel                matlab.ui.control.Label
        bac1EditField                 matlab.ui.control.EditField
        NotesTextAreaLabel            matlab.ui.control.Label
        NotesTextArea                 matlab.ui.control.TextArea
        DonPanel                      matlab.ui.container.Panel
        NhpgitrcndonEditFieldLabel    matlab.ui.control.Label
        NhpgitrcndonEditField         matlab.ui.control.EditField
        KtqudonTextAreaLabel          matlab.ui.control.Label
        KtqudonTextArea               matlab.ui.control.TextArea
        Button3                       matlab.ui.control.StateButton
    end

    
    methods (Access = private)
        
function [a1, a0, r2] = Hoiquytuyentinh(app,x,y)
        n = length(x);
        sumx = 0;
        sumy = 0;
        sumxy = 0;
        sumx2 = 0;
        st = 0;
        sr = 0;
    for i = 1:n
        sumx = sumx + x(i);
        sumy = sumy + y(i);
        sumxy = sumxy + x(i)*y(i);
        sumx2 = sumx2 + x(i)*x(i);
    end
        xm = sumx/n;
        ym = sumy/n;
        a1 = (n*sumxy - sumx*sumy)/(n*sumx2 - sumx*sumx);
        a0 = ym - a1*xm;
    for i = 1:n
        st = st + (y(i) - ym)^2;
        sr = sr + (y(i) - a1*x(i) - a0)^2;
    end
        r2 = (st - sr)/st;
end  
      
        
        function [a, b, r2] = Hoiquyhammu(app,x,y)
            n = length(x);
            sumx = 0;
            sumy = 0;
            sumxy = 0;
            sumx2 = 0;
            st = 0;
            sr = 0;
        for i = 1:n
            sumx = sumx + x(i);
            sumy = sumy + y(i);
            sumxy = sumxy + x(i)*y(i);
            sumx2 = sumx2 + x(i)*x(i);
        end
            xm = sumx/n;
            ym = sumy/n;
            a1 = (n*sumxy - sumx*sumy)/(n*sumx2 - sumx*sumx);
            a0 = ym - a1*xm;
            a = 10^a0;
            b = a1;
        for i = 1:n
            st = st + (y(i) - ym)^2;
            sr = sr + (y(i) - a1*x(i) - a0)^2;
        end
             r2 = (st - sr)/st;
        end
        
        function [a, b, r2] = Hoiquyhamlogarit(app,x,y)
            n = length(x);
            sumx = 0;
            sumy = 0;
            sumxy = 0;
            sumx2 = 0;
            st = 0;
            sr = 0;
        for i = 1:n
           sumx = sumx + x(i);
           sumy = sumy + y(i);
           sumxy = sumxy + x(i)*y(i);
           sumx2 = sumx2 + x(i)*x(i);
        end
           xm = sumx/n;
           ym = sumy/n;
           a1 = (n*sumxy - sumx*sumy)/(n*sumx2 - sumx*sumx);
           a0 = ym - a1*xm;
           a = exp(a0);
           b = a1;
        for i = 1:n
            st = st + (y(i) - ym)^2;
            sr = sr + (y(i) - a1*x(i) - a0)^2;
        end
            r2 = (st - sr)/st;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Button
        function ButtonValueChanged(app, event)
        x = str2num(app.xEditField.Value);
        y = str2num(app.yEditField.Value);
        
        switch app.DropDownOption.Value
            case 'Tuyến tính'
                 [a1, a0, r2] = Hoiquytuyentinh(app, x, y);
                 app.KtquTextArea.Value = num2str([a1, a0, r2]);
                 app.KtquTextArea.Value = sprintf('Kết quả của PT: y = %.4f + %.4f*x\nGiá trị tương quan r2 là: %.4f',a0, a1, r2);
                       
            case 'Hàm mũ'
                [a, b, r2] = Hoiquyhammu(app, x, y);
                app.KtquTextArea.Value = num2str([a, b, r2]);
                app.KtquTextArea.Value = sprintf('Kết quả của PT: y = %f*x^%f\nGiá trị tương quan r2 là: %.4f',a, b,r2);

            otherwise
                [a, b, r2] = Hoiquyhamlogarit(app, x, y);
                app.KtquTextArea.Value = num2str([a, b, r2]);
                app.KtquTextArea.Value = sprintf('Kết quả của PT: y = %f*e^%f*x\nGiá trị tương quan r2 là: %.4f',a, b, r2);

        end

        end

        % Value changed function: Button2
        function Button2ValueChanged(app, event)
            %Vẽ Hình
          x = str2num(app.xEditField.Value);
          y = str2num(app.yEditField.Value);
          bac1 = str2num(app.bac1EditField.Value);
          p = polyfit(x, y, bac1);
          y_fit = polyval(p, x);    
          plot(app.UIAxes, x, y, 'o', x, y_fit, '-');
            
        end

        % Value changed function: Button3
        function Button3ValueChanged(app, event)
          t = str2num(app.NhpgitrcndonEditField.Value);
          x = str2num(app.xEditField.Value);
          y = str2num(app.yEditField.Value);
     switch app.DropDownOption.Value
         case 'Tuyến tính'
             [a1, a0] = Hoiquytuyentinh(app, x, y);
             predict_y = a1.*t + a0; 
         case 'Hàm mũ'
             [a, b] = Hoiquyhammu(app, x, y);
             predict_y = a.*t.^b;
         otherwise
             [a, b] = Hoiquyhamlogarit(app, x, y);
             predict_y = a.*exp(b.*t);
     end
            
          app.KtqudonTextArea.Value = sprintf('Giá trị cần dự đoán là: %.4f',predict_y);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.NhpdliuPanel = uipanel(app.UIFigure);
            app.NhpdliuPanel.Title = 'Nhập dữ liệu';
            app.Button = uibutton(app.NhpdliuPanel, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = 'Button';
            app.ChnphngphphiquyDropDownLabel = uilabel(app.NhpdliuPanel);
            app.ChnphngphphiquyDropDownLabel.Text = 'Chọn phương pháp hồi quy';
            app.DropDownOption = uidropdown(app.NhpdliuPanel);
            app.DropDownOption.Items = {'Tuyến tính', 'Hàm mũ', 'Logarit', 'None'};
            app.DropDownOption.Value = 'Tuyến tính';
            app.xEditFieldLabel = uilabel(app.NhpdliuPanel);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.NhpdliuPanel, 'text');
            app.yEditFieldLabel = uilabel(app.NhpdliuPanel);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.NhpdliuPanel, 'text');
            app.KtquTextAreaLabel = uilabel(app.NhpdliuPanel);
            app.KtquTextAreaLabel.Text = 'Kết quả';
            app.KtquTextArea = uitextarea(app.NhpdliuPanel);
            app.VthPanel = uipanel(app.UIFigure);
            app.VthPanel.Title = 'Vẽ đồ thị';
            app.UIAxes = uiaxes(app.VthPanel);
            title(app.UIAxes, 'Đồ thị hồi quy')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Button2 = uibutton(app.VthPanel, 'state');
            app.Button2.ValueChangedFcn = createCallbackFcn(app, @Button2ValueChanged, true);
            app.Button2.Text = 'Button2';
            app.ChnbcathcLabel = uilabel(app.VthPanel);
            app.ChnbcathcLabel.Text = 'Chọn bậc đa thức';
            app.bac1EditField = uieditfield(app.VthPanel, 'text');
            app.NotesTextAreaLabel = uilabel(app.UIFigure);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.NotesTextArea = uitextarea(app.UIFigure);
            app.NotesTextArea.Value = {'1. Hai mảng phải cùng kích thước. VD: x = 1 2 3'; '                   y = 4 5 6'; '2. Bậc đa thức thường chọn bằng 1'; '3. Khi vẽ đồ thị chỉ chọn tối đa 1 PP không được chọn PP khác khi vẽ. VD: Nếu chọn PP tuyến tính thì sẽ vẽ PP tuyến tính, nếu đổi sang PP khác sẽ không vẽ được'};
            app.DonPanel = uipanel(app.UIFigure);
            app.DonPanel.Title = 'Dự đoán';
            app.NhpgitrcndonEditFieldLabel = uilabel(app.DonPanel);
            app.NhpgitrcndonEditFieldLabel.Text = 'Nhập giá trị cần dự đoán';
            app.NhpgitrcndonEditField = uieditfield(app.DonPanel, 'text');
            app.KtqudonTextAreaLabel = uilabel(app.DonPanel);
            app.KtqudonTextAreaLabel.Text = 'Kết quả dự đoán';
            app.KtqudonTextArea = uitextarea(app.DonPanel);
            app.Button3 = uibutton(app.DonPanel, 'state');
            app.Button3.ValueChangedFcn = createCallbackFcn(app, @Button3ValueChanged, true);
            app.Button3.Text = 'Button3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjectPPT_21200326

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end